classdef Software_CelularClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StatusBarLabel                 matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        SavingOptionsPanel             matlab.ui.container.Panel
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        TableCheckBox                  matlab.ui.control.CheckBox
        SaveButton                     matlab.ui.control.Button
        AffliationsPanel               matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        LegendPanel                    matlab.ui.container.Panel
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Allowstosegmenttheimagewithathresholdintensitybetween0and1Label  matlab.ui.control.Label
        GaussianDistribuitionPanel     matlab.ui.container.Panel
        ParameterDropDown              matlab.ui.control.DropDown
        ParameterDropDownLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UITable                        matlab.ui.control.Table
        CellsSelectionThresholdsPanel  matlab.ui.container.Panel
        ExcentricityEditField          matlab.ui.control.NumericEditField
        ExcentricityEditFieldLabel     matlab.ui.control.Label
        AreaEditField                  matlab.ui.control.NumericEditField
        AreaEditFieldLabel             matlab.ui.control.Label
        ImageSettingsPanel             matlab.ui.container.Panel
        BinaryEditField                matlab.ui.control.NumericEditField
        BinaryEditFieldLabel           matlab.ui.control.Label
        BorderSpinner                  matlab.ui.control.Spinner
        BorderSpinnerLabel             matlab.ui.control.Label
        RangeSpinner                   matlab.ui.control.Spinner
        RangeSpinnerLabel              matlab.ui.control.Label
        StrelDropDown                  matlab.ui.control.DropDown
        StrelDropDownLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        selectedCells
        tabela
    end
    
    methods (Access = private)
        
        % Vamos criar uma mascara e aplicá-la a cada celula da imagem
        function M_I=criarMASK(~,I_CB,S_C)

            % Criar uma nova máscara para células selecionadas
            mask_S_C = zeros(size(I_CB));

            for i = 1:numel(S_C)
                mask_S_C(S_C(i).PixelIdxList) = 1;
            end

            % Aplicar a nova máscara às células selecionadas
            M_I = I_CB.*logical(uint8(mask_S_C));

        end

        % Para a ação de ser o utilizador escolher uma pasta, é só descomentar esta
        % função e comentar a outra

        function combined_image = montar_imagem(~,diretorio, extensao)
            arquivos = dir(fullfile(diretorio, extensao));

            imagens = cell(1, numel(arquivos));

            % Ler as imagens e armazená-las em uma célula
            for i = 1:numel(arquivos)
                nome_arquivo = fullfile(diretorio, arquivos(i).name);
                img = imread(nome_arquivo);
                imagens{i} = img;
            end

            % Encontrar as dimensões máximas entre as imagens
            max_height = 0;
            max_width = 0;
            is_color = false;

            for i = 1:numel(imagens)
                [h, w, c] = size(imagens{i});
                max_height = max(max_height, h);
                max_width = max(max_width, w);
                if c == 3
                    is_color = true;
                end
            end

            % Calcular as dimensões da imagem combinada
            num_images = numel(imagens);
            rows = ceil(sqrt(num_images));
            cols = ceil(num_images / rows);

            if is_color
                combined_image = uint8(ones(max_height * rows, max_width * cols, 3) * 255);
            else
                combined_image = uint8(ones(max_height * rows, max_width * cols) * 255);
            end

            % Preencher a imagem combinada com as imagens lidas
            for i = 1:num_images
                [row, col] = ind2sub([rows, cols], i);
                row_idx = (row - 1) * max_height + 1;
                col_idx = (col - 1) * max_width + 1;

                img = imagens{i};
                [h, w, ~] = size(img);

                combined_image(row_idx:row_idx + h - 1, col_idx:col_idx + w - 1, :) = img;
            end

            % Mostrar a imagem combinada
            % imshow(combined_image);
        end

        % Vamos criar uma função que nos mostre as células selecionadas na imagem original com informações
        % de diâmetro e excentricidade.
        function Resultados(~,I,S_C,M_I)
            figure;
            imshow(I);
            hold on
            for j = 1:numel(S_C)
                centroid = S_C(j).Centroid; % Centroide
                eccentricity = S_C(j).Eccentricity; % Excentricidade
                diameter = S_C(j).MajorAxisLength; % Diametro

                text(centroid(1), centroid(2), sprintf('Ecc: %.3f, Dim: %.2f', eccentricity, diameter), 'Color', 'yellow', 'FontSize', 8);
            end

            BW=activecontour(M_I,M_I,30,"edge","SmoothFactor",1,"ContractionBias",-1);
            visboundaries(BW,'Color','r');
            
        end

        % Vamos tratar da imagem inserida com GUI
        function I_CB=TratarImagem(~,I,Strel,Binary,Range,Border)

            % Converter para imagem binária
            IB = im2bw(I, Binary);

            % Aplicar abertura para remover pequenos detalhes
            SE = strel(Strel,Range);
            I_OP = imopen(IB, SE);

            % Remover bordas conectadas à borda da imagem
            I_CB = imclearborder(~I_OP, Border);

        end

        % Para a ação de ser o utilizador a escolher uma pasta com imagens, é só descomentar esta
        % função e comentar a outra

        function [S_C1,TABLE,Numero_F,Numero_T] = SelecionamentoCelulas(app,I_CB, areaThreshold, eccentricityThreshold)
            stats = regionprops(I_CB, 'Area', 'Eccentricity', 'PixelIdxList','Centroid','MajorAxisLength','Circularity','FilledArea','EquivDiameter');

            % Inicializar a estrutura para armazenar as estatísticas
            S_C = struct('Area', {}, 'Eccentricity', {}, 'PixelIdxList', {}, 'Centroid', {}, 'MajorAxisLength', {},'Circularity',{},'FilledArea',{},'EquivDiameter',{});
            S_C1 = struct('Area', {}, 'Eccentricity', {}, 'PixelIdxList', {}, 'Centroid', {}, 'MajorAxisLength', {},'Circularity',{},'FilledArea',{},'EquivDiameter',{});

            % Armazenar estatísticas na estrutura
            count = 0;
            for i = 1:numel(stats)
                if stats(i).Area > areaThreshold && stats(i).Eccentricity < eccentricityThreshold
                    count = count + 1;
                    S_C(count).Area = stats(i).Area*66^2;
                    S_C(count).Eccentricity = stats(i).Eccentricity;
                    S_C(count).PixelIdxList = stats(i).PixelIdxList;
                    S_C(count).Centroid = stats(i).Centroid;
                    S_C(count).MajorAxisLength = stats(i).MajorAxisLength*66;
                    S_C(count).Circularity =stats(i).Circularity;
                    S_C(count).FilledArea =stats(i).FilledArea*66^2;
                    S_C(count).EquivDiameter =stats(i).EquivDiameter*66;
                end
            end
            Numero_T=size(S_C);

            count1 = 0;
            for i = 1:numel(stats)
                if stats(i).Area > areaThreshold && stats(i).Eccentricity < eccentricityThreshold
                    count1 = count1 + 1;
                    S_C1(count1).Area = stats(i).Area*66^2;
                    S_C1(count1).Eccentricity = stats(i).Eccentricity;
                    S_C1(count1).PixelIdxList = stats(i).PixelIdxList;
                    S_C1(count1).Centroid = stats(i).Centroid;
                    S_C1(count1).MajorAxisLength = stats(i).MajorAxisLength*66;
                    S_C1(count1).Circularity =stats(i).Circularity;
                    S_C1(count1).FilledArea =stats(i).FilledArea*66^2;
                    S_C1(count1).EquivDiameter =stats(i).EquivDiameter*66;
                end
            end
            % Converter a estrutura para uma tabela
            tableData = struct2table(S_C1);

            % Definir os nomes das colunas
            colNames = {'Area (micro)', 'Eccentricity', 'PixelIdxList', 'Centroid', 'MajorAxisLength (micro)','Circularity','FilledArea (micro)','EquivDiameter (micro)'};

            % Atualizar a UITable com os dados e nomes das colunas
            app.UITable.Data = tableData;
            app.UITable.ColumnName = colNames;
            
            % Imprimir a tabela
            TABLE=tableData;
            Numero_F=size(S_C1);
        end

        % Vamos então distribuir os dados de uma forma gaussiana
        function DistribuicaoGaussiana(app,S_C,avaliation_text)

            % Calculando o avaliação das células selecionadas
            switch (avaliation_text)
                case "Excentricity"
                    A = [S_C.Eccentricity]; % Obtém a avaliação
                case "Area"
                    A = [S_C.Area]; % Obtém a avaliação
                case "Diameter"
                    A = [S_C.MajorAxisLength]; % Obtém a avaliação
                case "EquivDiameter"
                    A = [S_C.EquivDiameter]; % Obtém a avaliação
                case "Circularity"
                    A = [S_C.Circularity]; % Obtém a avaliação
            end

            % Ajustar uma distribuição gaussiana para o diâmetro das células
            num_gaussianas_avaliation = 1;
            modelo_gaussiano_avaliation = fitgmdist(A', num_gaussianas_avaliation);

            % Plotar as distribuições gaussianas para a avaliação

            histogram(app.UIAxes,A, 'Normalization', 'pdf'); % Histograma normalizado da avaliaçao
            hold(app.UIAxes, 'on');
            x_avaliation = linspace(min(A), max(A), 1000);
            pdf_gaussiana_avaliation = pdf(modelo_gaussiano_avaliation, x_avaliation');
            plot(app.UIAxes,x_avaliation, pdf_gaussiana_avaliation, 'r', 'LineWidth', 2); % PDF da gaussiana ajustada para a avaliação
            app.UIAxes.Title.String = ['Distribuição Gaussiana para' ' ' avaliation_text ' ' 'das Células'];
            app.UIAxes.XLabel.String = avaliation_text;
            app.UIAxes.YLabel.String = 'Densidade de probabilidade';
            legend(app.UIAxes, 'Histograma', 'PDF da Gaussiana');
            hold(app.UIAxes,'off');

        end

        function AtualizarStatusBar(app, mensagem)
            app.StatusBarLabel.Text= mensagem;
            drawnow; % Força a atualização da interface para mostrar a mensagem imediatamente
        end

%         function SelectPlateAreas(app)
%             % Limpar a figura atual, se houver alguma
%             cla(app.UIAxes);
% 
%             % Pedir ao usuário para carregar a imagem da placa
%             [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Select Plate Image');
%             if ~isequal(fileName, 0)
%                 % Carregar a imagem selecionada
%                 plateImage = imread(fullfile(filePath, fileName));
% 
%                 % Mostrar a imagem na interface
%                 imshow(plateImage, 'Parent', app.UIAxes);
%                 title(app.UIAxes, 'Select Plate Areas');
%                 axis(app.UIAxes, 'on');
% 
%                 % Pedir ao usuário para definir o número de áreas na placa
%                 numAreas = inputdlg('Enter the number of areas in the plate:', 'Number of Areas', [1 50]);
%                 if ~isempty(numAreas)
%                     numAreas = str2double(numAreas);
% 
%                     % Permitir ao usuário selecionar as áreas na placa
%                     areas = cell(numAreas, 1);
%                     for i = 1:numAreas
%                         title(app.UIAxes, ['Select Area ' num2str(i)]);
%                         msg = sprintf('Select Area %d by drawing a rectangle. Right-click to finish.', i);
%                         msgbox(msg, 'Select Area', 'help');
%                         areas{i} = drawrectangle(app.UIAxes);
%                     end
% 
%                     % Armazenar as áreas selecionadas para análise posterior
%                     app.SelectedAreas = areas;
%                     app.PlateImage = plateImage;
% 
%                     msgbox('Areas selected successfully!', 'Success');
%                 end
%             end
%         end
%         function PlateSelection(app)
% 
%             % Criar ou carregar uma base de dados
%             data = struct();
%             data.Zone1 = struct('Diameter',app.tabela.Diameter(:), 'Circularity', app.tabela.Circularity);
%             data.Zone2 = struct('Diameter', 15, 'Circularity', 25);
% 
%             % Salvar a base de dados em um arquivo (por exemplo, Excel)
%             % save('plaque_data.mat', 'data');
% 
%             % Ao clicar em uma zona específica (supondo que a zona clicada seja 'Zone1')
%             clickedZone = 'Zone1';
% 
%             % Acessar os dados correspondentes à zona clicada
%             if isfield(data, clickedZone)
%                 zoneData = data.(clickedZone);
%                 disp(zoneData); % Exibir os dados da zona clicada
%             else
%                 disp('Dados não encontrados para esta zona.');
%             end
% 
% 
%         end
       

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Mostrar mensagem inicial na barra de status
            AtualizarStatusBar(app, 'Iniciando o carregamento do código... ');

            % Iniciar o relógio
            tic;

            % Código

            % Vamos Limpar os dados existentes
            % Vamos Buscar o caminho com as imagens e a respetiva extensão
            addpath('C:\Users\bruno\OneDrive\Ambiente de Trabalho\bu\Percurso Académico\Universidade de Aveiro\Mestrado em Engenharia Biomédica\Análise e Interpretação de Imagem\Projeto\Código\Imagens\Esferóides')
            folder='C:\Users\bruno\OneDrive\Ambiente de Trabalho\bu\Percurso Académico\Universidade de Aveiro\Mestrado em Engenharia Biomédica\Análise e Interpretação de Imagem\Projeto\Código\Imagens\Esferóides';
            type='*.tiff';

            % folder=uigetdir('C:\Users\bruno\OneDrive',type); % Para o caso de
            % querer is buscar a pasta


        % Etapa 1: Montar a imagem
        I = montar_imagem(app, folder, type);
        AtualizarStatusBar(app, 'Imagem carregada.');

        % Etapa 2: Tratar a imagem
        I_CB = TratarImagem(app, I, app.StrelDropDown.Value, app.BinaryEditField.Value, app.RangeSpinner.Value, app.BorderSpinner.Value);
        AtualizarStatusBar(app, 'Imagem tratada.');

        % Etapa 3: Selecionar células e mostrar os parametros numa
        % tabela
        [app.selectedCells,app.tabela,Numero_F,Numero_T] = SelecionamentoCelulas(app, I_CB, app.AreaEditField.Value, app.ExcentricityEditField.Value);
        AtualizarStatusBar(app, 'Células selecionadas.');

        % Etapa 4: Criar e aplicar máscara
        masked_image = criarMASK(app, I_CB, app.selectedCells);
        AtualizarStatusBar(app, 'Máscara aplicada.');

        % Etapa 5: Mostrar resultados em graficos e histogramas
        ParameterDropDownValueChanged(app,event)
        % Etapa 6: Mostrar a imagem com a máscara e os resultados
        Resultados(app,I,app.selectedCells,masked_image);

        % Calcular o tempo decorrido
        tempo = toc;

        % Atualizar a barra de status com o tempo decorrido
        mensagem = sprintf('O código foi carregado em %.2f segundos.', tempo);
        AtualizarStatusBar(app, mensagem);

        % Número de células mortas
        mensagem = sprintf('Número de Esferoides Mortos: %dn', Numero_T(2)-Numero_F(2));
        AtualizarStatusBar(app, mensagem);
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            value = app.ParameterDropDown.Value;
            DistribuicaoGaussiana(app,app.selectedCells,value)
            AtualizarStatusBar(app, ['Distribution applied to' ' ' value]);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            nome_separador = app.NameEditField.Value;
            if app.TableCheckBox.Value

                % Nome da coluna a ser removida
                nomeDaColunaARemover = 'PixelIdxList';  % Substitui isso com o nome da tua coluna

                % Obter os nomes das colunas
                nomesColunas = app.tabela.Properties.VariableNames;

                % Encontrar o índice da coluna a ser removida
                indiceColunaARemover = strcmp(nomeDaColunaARemover, nomesColunas);

                % Criar uma nova tabela sem a coluna
                novaTabela = app.tabela(:, ~indiceColunaARemover);
                % Salve a tabela no arquivo Excel
                try
                    writetable(novaTabela, 'Base_de_dados.xlsx', 'Sheet', nome_separador);
                    msgbox(['Dados salvos no separador: ' nome_separador], 'Salvo com sucesso');
                catch
                    msgbox('Erro ao salvar os dados.', 'Erro');
                end
            else
                msgbox('Não há dados para salvar.', 'Nenhum dado encontrado');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageSettingsPanel = uipanel(app.UIFigure);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.StrelDropDownLabel = uilabel(app.ImageSettingsPanel);
            app.StrelDropDownLabel.Text = 'Strel';
            app.StrelDropDown = uidropdown(app.ImageSettingsPanel);
            app.StrelDropDown.Items = {'disk', 'rectangle', 'sphere', 'cube'};
            app.StrelDropDown.Value = 'disk';
            app.RangeSpinnerLabel = uilabel(app.ImageSettingsPanel);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner = uispinner(app.ImageSettingsPanel);
            app.RangeSpinner.Limits = [0 32];
            app.RangeSpinner.Value = 2;
            app.BorderSpinnerLabel = uilabel(app.ImageSettingsPanel);
            app.BorderSpinnerLabel.Text = 'Border';
            app.BorderSpinner = uispinner(app.ImageSettingsPanel);
            app.BorderSpinner.Limits = [0 32];
            app.BorderSpinner.Value = 4;
            app.BinaryEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.BinaryEditFieldLabel.Text = 'Binary';
            app.BinaryEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.BinaryEditField.Limits = [0 1];
            app.BinaryEditField.Value = 0.25;
            app.CellsSelectionThresholdsPanel = uipanel(app.UIFigure);
            app.CellsSelectionThresholdsPanel.Title = 'Cells Selection (Thresholds)';
            app.AreaEditFieldLabel = uilabel(app.CellsSelectionThresholdsPanel);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.CellsSelectionThresholdsPanel, 'numeric');
            app.AreaEditField.Limits = [0 Inf];
            app.AreaEditField.Value = 20000;
            app.ExcentricityEditFieldLabel = uilabel(app.CellsSelectionThresholdsPanel);
            app.ExcentricityEditFieldLabel.Text = 'Excentricity';
            app.ExcentricityEditField = uieditfield(app.CellsSelectionThresholdsPanel, 'numeric');
            app.ExcentricityEditField.Limits = [0 1];
            app.ExcentricityEditField.Value = 0.7;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GaussianDistribuitionPanel = uipanel(app.UIFigure);
            app.GaussianDistribuitionPanel.Title = 'Gaussian Distribuition';
            app.UIAxes = uiaxes(app.GaussianDistribuitionPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ParameterDropDownLabel = uilabel(app.GaussianDistribuitionPanel);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.GaussianDistribuitionPanel);
            app.ParameterDropDown.Items = {'Excentricity', 'EquivDiameter', 'Diameter', 'Area', 'Circularity'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = 'Excentricity';
            app.LegendPanel = uipanel(app.UIFigure);
            app.LegendPanel.Title = 'Legend';
            app.Allowstosegmenttheimagewithathresholdintensitybetween0and1Label = uilabel(app.LegendPanel);
            app.Allowstosegmenttheimagewithathresholdintensitybetween0and1Label.Text = 'Allows to segment the image with a threshold intensity between 0 and 1';
            app.Label = uilabel(app.LegendPanel);
            app.Label.Text = 'Structuring elements with  a specific format';
            app.Label_2 = uilabel(app.LegendPanel);
            app.Label_2.Text = 'Corresponds to the measure range of the specific format applied';
            app.Label_3 = uilabel(app.LegendPanel);
            app.Label_3.Text = 'Corresponds to the level of  elimination of the elements touching the borders.';
            app.Label_4 = uilabel(app.LegendPanel);
            app.Label_4.Text = 'Binary';
            app.Label_5 = uilabel(app.LegendPanel);
            app.Label_5.Text = 'Strel';
            app.Label_6 = uilabel(app.LegendPanel);
            app.Label_6.Text = 'Range';
            app.Label_7 = uilabel(app.LegendPanel);
            app.Label_7.Text = 'Border';
            app.Label_8 = uilabel(app.LegendPanel);
            app.Label_8.Text = '-';
            app.Label_9 = uilabel(app.LegendPanel);
            app.Label_9.Text = '-';
            app.Label_10 = uilabel(app.LegendPanel);
            app.Label_10.Text = '-';
            app.Label_11 = uilabel(app.LegendPanel);
            app.Label_11.Text = '-';
            app.AffliationsPanel = uipanel(app.UIFigure);
            app.AffliationsPanel.Title = 'Affliations';
            app.Image = uiimage(app.AffliationsPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UA_Cor.jpg');
            app.SavingOptionsPanel = uipanel(app.UIFigure);
            app.SavingOptionsPanel.Title = 'Saving Options';
            app.SaveButton = uibutton(app.SavingOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TableCheckBox = uicheckbox(app.SavingOptionsPanel);
            app.TableCheckBox.Text = 'Table';
            app.NameEditFieldLabel = uilabel(app.SavingOptionsPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.SavingOptionsPanel, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusBarLabel = uilabel(app.UIFigure);
            app.StatusBarLabel.Text = 'Status Bar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Software_CelularClassification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end